classdef FlightCockpit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightInstrumentsFlightDataPlaybackUIFigure  matlab.ui.Figure
        SimulateTrajectoryButton       matlab.ui.control.Button
        SimulateDashboardButton        matlab.ui.control.Button
        FlightCockpitDataDisplayLabel  matlab.ui.control.Label
        Time000secSlider               matlab.ui.control.Slider
        Time000secSliderLabel          matlab.ui.control.Label
        ClimbIndicator                 Aero.ui.control.ClimbIndicator
        HeadingIndicator               Aero.ui.control.HeadingIndicator
        TurnCoordinator                Aero.ui.control.TurnCoordinator
        Altimeter                      Aero.ui.control.Altimeter
        ArtificialHorizon              Aero.ui.control.ArtificialHorizon
        AirspeedIndicator              Aero.ui.control.AirspeedIndicator
        Image                          matlab.ui.control.Image
    end

   
    properties (Access = public) 
        simdata % Saved flight data [time X Y Z phi theta psi]
        animObj % Aero.Animation object
        output  % The output states of the GTM Model
        t       % Time data
        tplot   % Interpolated time data for plotting
        Xeom    % EOM state data
        y       % State data from simulation
        dist_lat % Latitude distance
        dist_lon % Longitude distance
        alt      % Altitude data
        Vehicletop      % Top view of the vehicle
        Vehiclebot      % Bottom view of the vehicle
        simulationTime
    end
    
    methods (Access = private)
    
    function plotDynamicTrajectory(app)
        %% Interpolate data to match the tplot timeline
        app.tplot = [0:.1:max(app.simulationTime)];
        X_ani = interp1(app.simulationTime, app.Xeom, app.tplot);
        Y_ani = interp1(app.simulationTime, app.y(:, 1:5), app.tplot);
        lat_ani = interp1(app.simulationTime, app.dist_lat, app.tplot);
        lon_ani = interp1(app.simulationTime, app.dist_lon, app.tplot);
        alt_ani = interp1(app.simulationTime, app.alt, app.tplot);

        %% Setup Dynamic Plots
        h = figure(3);  % Reference to the existing figure
        clf(h);  % Clear the figure to prepare for new plots
        set(h, 'Position', [20, 20, 1200, 800]);  % Optional: adjust the position if needed

        % Alpha/Beta Plot Initialization
        hAlphaBeta = axes('position', [.1 .75 .23 .13]);
        pAlpha = plot(hAlphaBeta, app.tplot(1), X_ani(1, 3), 'r', 'LineWidth', 1.5); % Alpha
        hold on;
        pBeta = plot(hAlphaBeta, app.tplot(1), X_ani(1, 4), 'b', 'LineWidth', 1.5); % Beta
        grid on;
        legend({'\alpha', '\beta'}, 'Location', 'SouthEast');
        xlabel('Time (sec)');
        ylabel('\alpha (deg), \beta (deg)');
        title('Alpha/Beta');
        ylim([-50 50]);

        % Flight Path and Airspeed Initialization
        hFlightPath = axes('position', [.1 .55 .23 .13]);
        hGamma = plot(hFlightPath, app.tplot(1), Y_ani(1, 5), 'r', 'LineWidth', 1.5); % Gamma
        hold on;
        hEAS = plot(hFlightPath, app.tplot(1), Y_ani(1, 1), 'b', 'LineWidth', 1.5); % Air-Speed
        grid on;
        legend({'\gamma', 'EAS'}, 'Location', 'SouthEast');
        xlabel('Time (sec)');
        ylabel('\gamma (deg), Equivalent Airspeed (knots)');
        title('Flight Path Angle and Airspeed');

        % Angular Rates Plot Initialization
        hAngularRates = axes('position', [.1 .35 .23 .13]);
        pP = plot(hAngularRates, app.tplot(1), X_ani(1, 4) * 180 / pi, 'r', 'LineWidth', 1.5); % p
        hold on;
        pQ = plot(hAngularRates, app.tplot(1), X_ani(1, 5) * 180 / pi, 'g', 'LineWidth', 1.5); % q
        pR = plot(hAngularRates, app.tplot(1), X_ani(1, 6) * 180 / pi, 'b', 'LineWidth', 1.5); % r
        legend({'p', 'q', 'r'}, 'Location', 'SouthEast');
        xlabel('Time (sec)');
        ylabel('Angular Rates (deg/sec)');
        title('Angular Rates');
        grid on;

        % Euler Angles Plot Initialization
        hEulerAngles = axes('position', [.1 .15 .23 .13]);
        pRoll = plot(hEulerAngles, app.tplot(1), X_ani(1, 10) * 180 / pi, 'r', 'LineWidth', 1.5); % Roll (phi)
        hold on;
        pPitch = plot(hEulerAngles, app.tplot(1), X_ani(1, 11) * 180 / pi, 'g', 'LineWidth', 1.5); % Pitch (theta)
        pYaw = plot(hEulerAngles, app.tplot(1), X_ani(1, 12) * 180 / pi, 'b', 'LineWidth', 1.5); % Yaw (psi)
        legend({'Roll', 'Pitch', 'Yaw'}, 'Location', 'NorthEast');
        xlabel('Time (sec)');
        ylabel('\phi (deg), \theta (deg), \psi (deg)');
        title('Euler Angles');
        grid on;

        % 3D Trajectory Plot Initialization
        hTrajectory = axes('position', [.45, .15, .5, .7]);
        plot3(hTrajectory, app.dist_lat, app.dist_lon, app.alt, 'k', 'LineWidth', 1.5); % Entire Trajectory
        hold on;
        h3DAircraft = plot3(hTrajectory, lat_ani(1), lon_ani(1), alt_ani(1), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
        grid on;
        xlabel('Lat. Crossrange (ft)');
        ylabel('Long. Crossrange (ft)');
        zlabel('Altitude (ft)');
        title('3D Oscillatory Spin Trajectory');
        view([25, 10]);

        %% Start Dynamic Plot Updates
        for i = 1:length(app.tplot)
            % Update Alpha/Beta plot
            set(pAlpha, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 3));
            set(pBeta, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 4));

            % Update Flight Path and Airspeed plot
            set(hGamma, 'XData', app.tplot(1:i), 'YData', Y_ani(1:i, 5));
            set(hEAS, 'XData', app.tplot(1:i), 'YData', Y_ani(1:i, 1));

            % Update Angular Rates plot
            set(pP, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 4) * 180 / pi);
            set(pQ, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 5) * 180 / pi);
            set(pR, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 6) * 180 / pi);

            % Update Euler Angles plot
            set(pRoll, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 10) * 180 / pi);
            set(pPitch, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 11) * 180 / pi);
            set(pYaw, 'XData', app.tplot(1:i), 'YData', X_ani(1:i, 12) * 180 / pi);

            % Update 3D Trajectory plot
            set(h3DAircraft, 'XData', lat_ani(i), 'YData', lon_ani(i), 'ZData', alt_ani(i));

            % Plot Aircraft Shape at Current Position
            % Top View
            fill3(app.Vehicletop(1, :) + lat_ani(i), app.Vehicletop(2, :) + lon_ani(i), app.Vehicletop(3, :) + alt_ani(i), 'r', 'FaceAlpha', 0.5);
            % Bottom View
            fill3(app.Vehiclebot(1, :) + lat_ani(i), app.Vehiclebot(2, :) + lon_ani(i), app.Vehiclebot(3, :) + alt_ani(i), 'b', 'FaceAlpha', 0.5);

            % Pause for animation effect
            pause(0.1);
        end

        close(figure(3));  % Destroy figure 3
    end

    function plotStaticTrajectory(app)
    %% Interpolate data to match the tplot timeline
    app.tplot = [0:.1:max(app.simulationTime)];
    X_ani = interp1(app.simulationTime, app.Xeom, app.tplot);
    Y_ani = interp1(app.simulationTime, app.y(:, 1:5), app.tplot);
    lat_ani = interp1(app.simulationTime, app.dist_lat, app.tplot);
    lon_ani = interp1(app.simulationTime, app.dist_lon, app.tplot);
    alt_ani = interp1(app.simulationTime, app.alt, app.tplot);

    %% Setup Static Plots
    h = figure(3);  % Reference to the existing figure
    clf(h);  % Clear the figure to prepare for new plots
    set(h, 'Position', [20, 20, 1200, 800]);  % Optional: adjust the position if needed

    % Alpha/Beta Plot
    hAlphaBeta = axes('position', [.1 .75 .23 .13]);
    plot(hAlphaBeta, app.tplot, X_ani(:, 3), 'r', 'LineWidth', 1.5); % Alpha
    hold on;
    plot(hAlphaBeta, app.tplot, X_ani(:, 4), 'b', 'LineWidth', 1.5); % Beta
    grid on;
    legend({'\alpha', '\beta'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('\alpha (deg), \beta (deg)');
    title('Alpha/Beta');
    ylim([-50 50]);

    % Flight Path and Airspeed Plot
    hFlightPath = axes('position', [.1 .55 .23 .13]);
    plot(hFlightPath, app.tplot, Y_ani(:, 5), 'r', 'LineWidth', 1.5); % Gamma
    hold on;
    plot(hFlightPath, app.tplot, Y_ani(:, 1), 'b', 'LineWidth', 1.5); % Air-Speed
    grid on;
    legend({'\gamma', 'EAS'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('\gamma (deg), Equivalent Airspeed (knots)');
    title('Flight Path Angle and Airspeed');

    % Angular Rates Plot
    hAngularRates = axes('position', [.1 .35 .23 .13]);
    plot(hAngularRates, app.tplot, X_ani(:, 4) * 180 / pi, 'r', 'LineWidth', 1.5); % p
    hold on;
    plot(hAngularRates, app.tplot, X_ani(:, 5) * 180 / pi, 'g', 'LineWidth', 1.5); % q
    plot(hAngularRates, app.tplot, X_ani(:, 6) * 180 / pi, 'b', 'LineWidth', 1.5); % r
    legend({'p', 'q', 'r'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('Angular Rates (deg/sec)');
    title('Angular Rates');
    grid on;

    % Euler Angles Plot
    hEulerAngles = axes('position', [.1 .15 .23 .13]);
    plot(hEulerAngles, app.tplot, X_ani(:, 10) * 180 / pi, 'r', 'LineWidth', 1.5); % Roll (phi)
    hold on;
    plot(hEulerAngles, app.tplot, X_ani(:, 11) * 180 / pi, 'g', 'LineWidth', 1.5); % Pitch (theta)
    plot(hEulerAngles, app.tplot, X_ani(:, 12) * 180 / pi, 'b', 'LineWidth', 1.5); % Yaw (psi)
    legend({'Roll', 'Pitch', 'Yaw'}, 'Location', 'NorthEast');
    xlabel('Time (sec)');
    ylabel('\phi (deg), \theta (deg), \psi (deg)');
    title('Euler Angles');
    grid on;

    % 3D Trajectory Plot
    hTrajectory = axes('position', [.45, .15, .5, .7]);
    plot3(hTrajectory, app.dist_lat, app.dist_lon, app.alt, 'k', 'LineWidth', 1.5); % Entire Trajectory
    hold on;
    plot3(hTrajectory, lat_ani, lon_ani, alt_ani, 'r', 'LineWidth', 1.5); % Aircraft Path
    grid on;
    xlabel('Lat. Crossrange (ft)');
    ylabel('Long. Crossrange (ft)');
    zlabel('Altitude (ft)');
    title('3D Oscillatory Spin Trajectory');
    view([25, 10]);

    % Plot Aircraft Shape at Final Position
    fill3(app.Vehicletop(1, :) + lat_ani(end), app.Vehicletop(2, :) + lon_ani(end), app.Vehicletop(3, :) + alt_ani(end), 'r', 'FaceAlpha', 0.5);
    fill3(app.Vehiclebot(1, :) + lat_ani(end), app.Vehiclebot(2, :) + lon_ani(end), app.Vehiclebot(3, :) + alt_ani(end), 'b', 'FaceAlpha', 0.5);

    end

    function plotStaticTrajectoryAtTime(app, time)
    %% Interpolate data to match the tplot timeline
    app.tplot = [0:.1:max(app.simulationTime)];
    X_ani = interp1(app.simulationTime, app.Xeom, app.tplot);  % Interpolated state data
    Y_ani = interp1(app.simulationTime, app.y(:, 1:5), app.tplot);  % Interpolated additional data
    lat_ani = interp1(app.simulationTime, app.dist_lat, app.tplot);  % Interpolated latitude
    lon_ani = interp1(app.simulationTime, app.dist_lon, app.tplot);  % Interpolated longitude
    alt_ani = interp1(app.simulationTime, app.alt, app.tplot);  % Interpolated altitude

    %% Find the index corresponding to the specified time
    [~, idx] = min(abs(app.tplot - time));  % Find the closest time index

    %% Setup Static Plots
    % Check if figure already exists
    h = findobj('Type', 'figure', 'Tag', 'TrajectoryPlot');  % Search for the figure by its Tag
    if isempty(h)
        % If the figure does not exist, create it
        h = figure('Tag', 'TrajectoryPlot', 'Visible', 'off');  % Set Visible to off initially
        set(h, 'Position', [20, 20, 1200, 800]);  % Set position
    else
        % If it exists, bring it in the background without popping up
        set(0, 'CurrentFigure', h);  % Update the existing figure in the background
    end

    %% Now update the plots without bringing the window to the front
    set(h, 'Visible', 'off');  % Keep the figure invisible during updates

    % Alpha/Beta Plot
    hAlphaBeta = axes('position', [.1 .75 .23 .13]);
    plot(hAlphaBeta, app.tplot, X_ani(:, 3), 'r', 'LineWidth', 1.5); % Alpha
    hold(hAlphaBeta, 'on');
    plot(hAlphaBeta, app.tplot, X_ani(:, 4), 'b', 'LineWidth', 1.5); % Beta
    plot(hAlphaBeta, app.tplot(idx), X_ani(idx, 3), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Alpha at time
    plot(hAlphaBeta, app.tplot(idx), X_ani(idx, 4), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b'); % Beta at time
    grid on;
    legend({'\alpha', '\beta'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('\alpha (deg), \beta (deg)');
    title('Alpha/Beta');
    ylim([-50 50]);

    % Flight Path and Airspeed Plot
    hFlightPath = axes('position', [.1 .55 .23 .13]);
    plot(hFlightPath, app.tplot, Y_ani(:, 5), 'r', 'LineWidth', 1.5); % Gamma
    hold(hFlightPath, 'on');
    plot(hFlightPath, app.tplot, Y_ani(:, 1), 'b', 'LineWidth', 1.5); % Airspeed
    plot(hFlightPath, app.tplot(idx), Y_ani(idx, 5), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Gamma at time
    plot(hFlightPath, app.tplot(idx), Y_ani(idx, 1), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b'); % Airspeed at time
    grid on;
    legend({'\gamma', 'EAS'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('\gamma (deg), Equivalent Airspeed (knots)');
    title('Flight Path Angle and Airspeed');

    % Angular Rates Plot
    hAngularRates = axes('position', [.1 .35 .23 .13]);
    plot(hAngularRates, app.tplot, X_ani(:, 4) * 180 / pi, 'r', 'LineWidth', 1.5); % p
    hold(hAngularRates, 'on');
    plot(hAngularRates, app.tplot, X_ani(:, 5) * 180 / pi, 'g', 'LineWidth', 1.5); % q
    plot(hAngularRates, app.tplot, X_ani(:, 6) * 180 / pi, 'b', 'LineWidth', 1.5); % r
    plot(hAngularRates, app.tplot(idx), X_ani(idx, 4) * 180 / pi, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % p at time
    plot(hAngularRates, app.tplot(idx), X_ani(idx, 5) * 180 / pi, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g'); % q at time
    plot(hAngularRates, app.tplot(idx), X_ani(idx, 6) * 180 / pi, 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b'); % r at time
    legend({'p', 'q', 'r'}, 'Location', 'SouthEast');
    xlabel('Time (sec)');
    ylabel('Angular Rates (deg/sec)');
    title('Angular Rates');
    grid on;

    % Euler Angles Plot
    hEulerAngles = axes('position', [.1 .15 .23 .13]);
    plot(hEulerAngles, app.tplot, X_ani(:, 10) * 180 / pi, 'r', 'LineWidth', 1.5); % Roll (phi)
    hold(hEulerAngles, 'on');
    plot(hEulerAngles, app.tplot, X_ani(:, 11) * 180 / pi, 'g', 'LineWidth', 1.5); % Pitch (theta)
    plot(hEulerAngles, app.tplot, X_ani(:, 12) * 180 / pi, 'b', 'LineWidth', 1.5); % Yaw (psi)
    plot(hEulerAngles, app.tplot(idx), X_ani(idx, 10) * 180 / pi, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Roll at time
    plot(hEulerAngles, app.tplot(idx), X_ani(idx, 11) * 180 / pi, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g'); % Pitch at time
    plot(hEulerAngles, app.tplot(idx), X_ani(idx, 12) * 180 / pi, 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b'); % Yaw at time
    legend({'Roll', 'Pitch', 'Yaw'}, 'Location', 'NorthEast');
    xlabel('Time (sec)');
    ylabel('\phi (deg), \theta (deg), \psi (deg)');
    title('Euler Angles');
    grid on;

    % 3D Trajectory Plot
    hTrajectory = axes('position', [.45, .15, .5, .7]);
    plot3(hTrajectory, app.dist_lat, app.dist_lon, app.alt, 'k', 'LineWidth', 1.5); % Entire Trajectory
    hold(hTrajectory, 'on');
    plot3(hTrajectory, lat_ani(1:idx), lon_ani(1:idx), alt_ani(1:idx), 'r', 'LineWidth', 1.5); % Aircraft Path up to the selected time
    plot3(hTrajectory, lat_ani(idx), lon_ani(idx), alt_ani(idx), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Aircraft position at the selected time
    grid on;
    xlabel('Lat. Crossrange (ft)');
    ylabel('Long. Crossrange (ft)');
    zlabel('Altitude (ft)');
    title('3D Oscillatory Spin Trajectory');
    view([25, 10]);

    % Plot Aircraft Shape at Current Position
    fill3(app.Vehicletop(1, :) + lat_ani(idx), app.Vehicletop(2, :) + lon_ani(idx), app.Vehicletop(3, :) + alt_ani(idx), 'r', 'FaceAlpha', 0.5); % Top view
    hold on;  % Important: retain the current plot
    fill3(app.Vehiclebot(1, :) + lat_ani(idx), app.Vehiclebot(2, :) + lon_ani(idx), app.Vehiclebot(3, :) + alt_ani(idx), 'b', 'FaceAlpha', 0.5); % Bottom view
    hold off;  % Release the hold on the current plot

    % Set figure visible only after all updates
    set(h, 'Visible', 'on');

    %% Add Interactive Slider and Time Display
    % Create a slider to control time
    if isempty(findobj('Tag', 'TimeSlider'))
        % Slider to control time
        slider = uicontrol('Style', 'slider', ...
            'Min', 0, 'Max', 100, 'Value', 0, ...
            'Units', 'normalized', ...
            'Position', [.45 .02 .5 .03], ...  % Lower position
            'Tag', 'TimeSlider', ...
            'Callback', @(src, event) updatePlotAtTime(app, src.Value));

        % Text field to display the time value
        timeText = uicontrol('Style', 'text', ...
            'Units', 'normalized', ...
            'Position', [.7 .00 .1 .02], ...  % Just below the slider
            'Tag', 'TimeText', ...
            'String', 'Time: 0.00 sec');  % Initial time display
    end
end

function updatePlotAtTime(app, sliderValue)
    % This function updates the plot according to the slider value
    % Calculate the corresponding time based on the slider value
    maxTime = max(app.simulationTime);
    time = (sliderValue / 100) * maxTime;

    % Update the time text display
    timeText = findobj('Tag', 'TimeText');
    set(timeText, 'String', sprintf('Time: %.2f sec', time));  % Update the time display text

    % Re-call the main plotting function with the updated time
    plotStaticTrajectoryAtTime(app, time);
end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% About
            %
            % Author:     Omar Mourad
            % Email:      <mailto: st181901@stud.uni-stuttgart.de>
            % Created:    28.03.2024 
            
            %% ------------------ Initializing the Upset Condition ------------------
            selectedSimulationType = 'Oscillatory Spin';  % Change this to 'Steep Spiral' as needed

            %% Initialize the simulation
            switch selectedSimulationType
                case 'Oscillatory Spin'
                    run('OscillatorySpin.m'); % Run the Oscillatory Spin simulation
                    data = load('OscillatorySpinData.mat'); % Load corresponding data
                case 'Steep Spiral'
                    run('SteepSpiral.m'); % Run the Steep Spiral simulation
                    data = load('SteepSpiralData.mat'); % Load corresponding data
                otherwise
                    error('Unknown simulation type selected.');
            end

            % Assign data to app properties for further use
            app.t = data.t;                % Time data
            app.simulationTime = data.t;
            app.tplot = data.tplot;        % Interpolated time data for plotting
            app.y = data.y;                % State data from simulation
            app.Xeom = data.Xeom;          % EOM state data
            app.dist_lat = data.dist_lat;  % Latitude distance
            app.dist_lon = data.dist_lon;  % Longitude distance
            app.alt = data.alt;            % Altitude data
            
            %% Define simple airplane shape
            scale = 1;
            x1 = scale * ([0.0, -0.5, -2.0, -3.0, -4.0, -3.25, -5.5, -6.0, -6.0] + 3.0);
            y1 = scale * [0.0, 0.5, 0.5, 4.25, 4.5, 0.5, 0.5, 1.5, 0.0];
            app.Vehicletop = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; -0.01 * ones(1, 2 * length(x1))];
            app.Vehiclebot = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; 0.01 * ones(1, 2 * length(x1))];
            
            plotStaticTrajectory(app);
            plotStaticTrajectoryAtTime(app, 0);


            %% Loading Upset Flight Data
            app.output = app.y;
            % Load simulated GTM Upset flight data
            f2m = 0.3048;
            Data = [app.t app.dist_lat*f2m app.dist_lon*f2m app.alt*f2m app.output(:,16) app.output(:,17) app.output(:,18)];
            % Data = [time (secs), X-Distance (m), Y-Distance (m), Z-Distance (m), Altitude (m), Phi (rad), Theta (rad), Psi (rad)]
       
            yaw = Data(:,7);
            yaw(yaw<0) = yaw(yaw<0)+2*pi; % unwrap yaw angles
            Data(:,7) = yaw;
            app.simdata = Data;
            
            %% Creating a 3D Animation
            % Create animation object to visualize aircraft flight dynamics corresponding with saved data over time 
            app.animObj = Aero.Animation;
            app.animObj.createBody('pa24-250_orange.ac','Ac3d'); % Piper PA-24 Comanche geometry
            app.animObj.Bodies{1}.TimeseriesSourceType = 'Array6DoF'; % [time X Y Z phi theta psi]
            app.animObj.Bodies{1}.TimeSeriesSource = app.simdata;
            app.animObj.Camera.PositionFcn = @staticCameraPosition;
            app.animObj.Figure.Position = [app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(1)+625 app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(2) app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(3) app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(4)];
            app.animObj.updateBodies(app.simdata(1,1)); % Initialize animation window at t=0
            app.animObj.updateCamera(app.simdata(1,1));
            app.animObj.show();
        end

        % Value changing function: Time000secSlider
        function Time000secSliderValueChanging(app, event)
            
         % Display current time in slider component
        app.t = event.Value;
        app.Time000secSliderLabel.Text = sprintf('Time: %.1f sec', app.t);           
    
        % Find corresponding time data entry
        k = find(app.simdata(:,1) <= app.t, 1, 'last');  % Use 'last' to get the last valid index
    
        % Check if k is valid
        if isempty(k)
            return; % If no valid index, do nothing
        end
    
        % Altimeter Altitude
        app.Altimeter.Altitude = convlength(-app.simdata(k,4), 'm', 'ft');
    
        % Heading Indicator
        app.HeadingIndicator.Heading = convang(app.simdata(k,7), 'rad', 'deg');
    
        % Artificial Horizon
        app.ArtificialHorizon.Roll = convang(app.simdata(k,5), 'rad', 'deg');
        app.ArtificialHorizon.Pitch = convang(app.simdata(k,6), 'rad', 'deg');
    
        % AirSpeed Indicator
        if k > 1
            app.AirspeedIndicator.Airspeed = app.output(k,1);
    
            % Calculate vertical speed
            kts2mps = 0.514444;  % Knots to m/s conversion
            Vel_z = (app.output(k,1) * kts2mps) * sind(app.output(k,3)) * cosd(app.output(k,4));
    
            % Climb Indicator
            app.ClimbIndicator.ClimbRate = convvel(-Vel_z, 'm/s', 'ft/min');
    
            % Turn Coordinator
            roll_rate = app.output(k,10);
            yaw_rate = app.output(k,12);
            bank_angle = app.simdata(k,5);
            app.TurnCoordinator.Turn = convangvel(roll_rate * sin(bank_angle) + yaw_rate * cos(bank_angle), 'rad/s', 'deg/s');
            app.TurnCoordinator.Slip = 1/(2*pi) * convang(atan(yaw_rate * app.output(k,1) / 9.81) - app.simdata(k,5), 'rad', 'deg');
        else
            % time = 0
            app.ClimbIndicator.ClimbRate = 0;
            app.AirspeedIndicator.Airspeed = 0;
            app.TurnCoordinator.Slip = 0;
            app.TurnCoordinator.Turn = 0;
        end
    
        %% Update animation window display
        app.animObj.updateBodies(app.simdata(k,1));
        app.animObj.updateCamera(app.simdata(k,1));
            
        end

        % Close request function: 
        % FlightInstrumentsFlightDataPlaybackUIFigure
        function FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest(app, event)
            % Close animation figure with app
            delete(app.animObj);
            delete(app); 
           
        end

        % Button pushed function: SimulateDashboardButton
        function SimulateDashboardButtonPushed(app, event)
            % Set initial value of the slider to 0
            app.Time000secSlider.Value = 0;
            app.Time000secSliderLabel.Text = 'Time: 0.0 sec';
            
            % Define the total simulation time
            totalTime = app.simulationTime;
            
            % Create a timer
            app.t = timer;
            app.t.StartDelay = 0; % Start immediately
            app.t.Period = 0.1; % Update every 0.1 seconds (adjust as needed)
            app.t.ExecutionMode = 'fixedRate'; % Fixed rate execution
            app.t.BusyMode = 'queue'; % Queue additional executions if the timer is busy
            app.t.TimerFcn = @(~, ~) updateSlider(app, totalTime, app.t); % Update function
        
            % Start the timer
            start(app.t);
            
            % Store the timer in the app for potential use later (like stopping it)
            app.simulationTime = app.t;
        end
        
        function updateSlider(app, totalTime, t)
            % Get current slider value
            currentValue = app.Time000secSlider.Value;
        
            % Increment the slider value
            newValue = currentValue + 0.1; % Increase by 0.1 seconds each tick
        
            % Check if the new value exceeds totalTime
            if newValue >= totalTime
                newValue = totalTime; % Cap it at totalTime
                stop(t); % Stop the timer
                delete(t); % Clean up the timer
            end
        
            % Update slider and label
            app.Time000secSlider.Value = newValue;
            app.Time000secSliderLabel.Text = sprintf('Time: %.1f sec', newValue);
            
            % Call the slider's ValueChanging function
            event.Value = newValue; % Simulate the slider event
            Time000secSliderValueChanging(app, event); % Update dashboard components
        end

        % Button pushed function: SimulateTrajectoryButton
        function SimulateTrajectoryButtonPushed(app, event)
            plotDynamicTrajectory(app);
            plotStaticTrajectory(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightInstrumentsFlightDataPlaybackUIFigure = uifigure('Visible', 'off');
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Name = 'Flight Instruments - Flight Data Playback';
            app.FlightInstrumentsFlightDataPlaybackUIFigure.CloseRequestFcn = createCallbackFcn(app, @FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest, true);
            app.Image = uiimage(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Image.ImageSource = 'appdesignerInstrumentPanel.png';
            app.AirspeedIndicator = uiaeroairspeed(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.AirspeedIndicator.Limits = [25 250];
            app.ArtificialHorizon = uiaerohorizon(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Altimeter = uiaeroaltimeter(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.TurnCoordinator = uiaeroturn(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.HeadingIndicator = uiaeroheading(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator = uiaeroclimb(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator.MaximumRate = 8000;
            app.Time000secSliderLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Time000secSliderLabel.Text = 'Time: 00.0 sec';
            app.Time000secSlider = uislider(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Time000secSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Time000secSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.Time000secSlider.ValueChangingFcn = createCallbackFcn(app, @Time000secSliderValueChanging, true);
            app.Time000secSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.FlightCockpitDataDisplayLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.FlightCockpitDataDisplayLabel.Text = 'Flight Cockpit Data Display';
            app.SimulateDashboardButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.SimulateDashboardButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateDashboardButtonPushed, true);
            app.SimulateDashboardButton.Text = 'Simulate Dashboard';
            app.SimulateTrajectoryButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.SimulateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateTrajectoryButtonPushed, true);
            app.SimulateTrajectoryButton.Text = 'Simulate Trajectory';
            % Show the figure after all components are created
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightCockpit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightInstrumentsFlightDataPlaybackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightInstrumentsFlightDataPlaybackUIFigure)
        end
    end
end